classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        StatusLabel                    matlab.ui.control.Label
        HeartRateLabel                 matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        StartAcquisitionButton         matlab.ui.control.Button
        AcquisitionDurationLabel       matlab.ui.control.Label
        SamplingRateLabel              matlab.ui.control.Label
        AcquisitionDurationsEditField  matlab.ui.control.NumericEditField
        AcquisitionDurationsEditFieldLabel  matlab.ui.control.Label
        SamplingRateHzEditField        matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel   matlab.ui.control.Label
        ReadParameterButton            matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SampleRate
        Acqtime
        V
        t   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadParameterButton
        function ReadParameterButtonPushed(app, event)
            % 读取采样参数
            app.SampleRate = app.SamplingRateHzEditField.Value;
            app.Acqtime = app.AcquisitionDurationsEditField.Value;
            app.SamplingRateLabel.Text = ['Sampling Rate:', num2str(app.SampleRate), ' (Hz)'];
            app.AcquisitionDurationLabel.Text=['Acqusition Duration:', num2str(app.Acqtime),' (Sec)'];
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            % 配置采集设备
            s = daq.createSession('ni');
            addAnalogInputChannel(s, 'Dev11', 0, 'Voltage');
            s.Rate = app.SamplingRateHzEditField.Value;
            s.DurationInSeconds = app.AcquisitionDurationsEditField.Value;
            appFolder = fileparts(which('app1'));
            heartImage = app.Image;
            heartImage.ImageSource = fullfile(appFolder, 'heart_small.png');
            app.HeartRateLabel.Text = 'Heart Rate: -- bpm';
            app.StatusLabel.Text = 'Status: Acquiring...';
            app.StatusLabel.FontColor = [0.2 0.2 0.2];
            % 显示采集倒计时
            countdown = findobj(app.UIFigure, 'Tag', 'ECGCountdown');
            if isempty(countdown)
                countdown = uilabel(app.UIFigure, 'Tag', 'ECGCountdown', ...
                    'Position', [630 282 260 22]);
            end
            countdown.Text = sprintf('Acquiring: %d s remaining', ceil(s.DurationInSeconds));
            listener = addlistener(s, 'DataAvailable', ...
                @(src, event) ecg_countdown_update(countdown, src, event));
            app.StartAcquisitionButton.Enable = 'off';
            buttonCleanup = onCleanup(@() set(app.StartAcquisitionButton, 'Enable', 'on'));
            drawnow;
            % 采集原始信号
            [app.V, app.t] = s.startForeground;
            delete(listener);
            app.SampleRate = s.Rate;
            app.Acqtime = s.DurationInSeconds;
            release(s);
            app.SamplingRateLabel.Text = sprintf('Sampling Rate: %g Hz', app.SampleRate);
            app.AcquisitionDurationLabel.Text = sprintf('Duration: %g s', app.Acqtime);
            if isvalid(countdown), countdown.Text = 'Acquisition complete'; end
            drawnow;
            app.UIFigure.UserData = [];
            raw = app.V(:);
            % 预处理原始信号
            canFilter = app.SampleRate > 100;
            if canFilter
                result = ecg_preprocess(raw, app.SampleRate);
                filtered = result.filtered(:);
            else
                filtered = nan(size(raw));
            end
            t = app.t(:);
            % 计算单边幅度谱
            N = numel(raw);
            M = floor(N/2) + 1;
            f = (0:M-1)' * app.SampleRate / N;
            rawFFT = abs(fft(raw - mean(raw)) / N);
            filteredFFT = abs(fft(filtered - mean(filtered)) / N);
            rawAmplitude = rawFFT(1:M);
            filteredAmplitude = filteredFFT(1:M);
            if mod(N, 2) == 0
                rawAmplitude(2:end-1) = 2 * rawAmplitude(2:end-1);
                filteredAmplitude(2:end-1) = 2 * filteredAmplitude(2:end-1);
            else
                rawAmplitude(2:end) = 2 * rawAmplitude(2:end);
                filteredAmplitude(2:end) = 2 * filteredAmplitude(2:end);
            end
            % 保存本次采集结果
            app.UIFigure.UserData = struct('time', t, 'raw', raw, ...
                'filtered', filtered, 'fs', app.SampleRate, 'frequency', f, ...
                'rawAmplitude', rawAmplitude, 'filteredAmplitude', filteredAmplitude, 'locs', []);
            % 显示时域波形
            if canFilter
                plot(app.UIAxes, t, filtered);
                title(app.UIAxes, 'Filtered ECG');
            else
                plot(app.UIAxes, t, raw - 1.65);
                title(app.UIAxes, 'Raw ECG (minus 1.65 V)');
            end
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Voltage (V)');
            grid(app.UIAxes, 'on');
            if canFilter
                % 显示幅度谱
                plot(app.UIAxes2, f, rawAmplitude, f, filteredAmplitude);
                legend(app.UIAxes2, 'Raw', 'Filtered');
            else
                plot(app.UIAxes2, f, rawAmplitude);
                legend(app.UIAxes2, 'Raw');
            end
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Amplitude (V)');
            title(app.UIAxes2, 'Single-Sided Spectrum (Mean Removed)');
            xlim(app.UIAxes2, [0 min(100, app.SampleRate/2)]);
            grid(app.UIAxes2, 'on');
            app.HeartRateLabel.Position(3) = 230;
            app.StatusLabel.Position(3) = 230;
            app.HeartRateLabel.Text = 'Heart Rate: --';
            app.StatusLabel.Text = 'Status: --';
            app.StatusLabel.FontColor = [0 0 0];
            drawnow;
            rr = [];
            if canFilter
            % 检测并标记 R 峰
            locs = ecg_pan_tompkins(filtered, app.SampleRate);
            app.UIFigure.UserData.locs = locs;
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, t(locs), filtered(locs), 'ro', 'MarkerSize', 5);
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, 'Filtered ECG', 'R peaks');
            % 计算心率及状态
            rr = diff(t(locs));
            instantaneousHR = 60 ./ rr;
            meanHR = mean(instantaneousHR);
            stdHR = std(instantaneousHR, 0);
            app.HeartRateLabel.Text = sprintf('Heart Rate: %.1f %s %.1f bpm', meanHR, char(177), stdHR);
            if meanHR < 60
                app.StatusLabel.Text = 'Status: Low Heart Rate';
                app.StatusLabel.FontColor = [0.8 0 0];
            elseif meanHR > 120
                app.StatusLabel.Text = 'Status: High Heart Rate';
                app.StatusLabel.FontColor = [0.8 0 0];
            else
                app.StatusLabel.Text = 'Status: Normal';
                app.StatusLabel.FontColor = [0 0.5 0];
            end
            else
                app.HeartRateLabel.Text = 'Heart Rate: unavailable';
                app.StatusLabel.Text = 'Status: low fs - raw only';
            end
            % 播放心跳动画与声音
            if ~isempty(rr) && all(isfinite(rr)) && all(rr > 0)
                if meanHR < 60
                    smallImage = fullfile(appFolder, 'gray_small.png');
                    largeImage = fullfile(appFolder, 'gray_large.png');
                    alertFile = fullfile(appFolder, 'sneeze.wav');
                    [alertSound, alertFs] = audioread(alertFile);
                    alertPlayer = audioplayer(alertSound, alertFs);
                    playblocking(alertPlayer);
                elseif meanHR > 120
                    smallImage = fullfile(appFolder, 'broken_small.png');
                    largeImage = fullfile(appFolder, 'broken_large.png');
                    alertFile = fullfile(appFolder, 'glass_break.wav');
                    [alertSound, alertFs] = audioread(alertFile);
                    alertPlayer = audioplayer(alertSound, alertFs);
                    playblocking(alertPlayer);
                else
                    smallImage = fullfile(appFolder, 'heart_small.png');
                    largeImage = fullfile(appFolder, 'heart_big.png');
                end
                audioFs = 22050;
                ta = (0:round(0.09 * audioFs)-1)' / audioFs;
                lub = sin(2 * pi * 75 * ta) .* exp(-45 * ta);
                dub = 0.7 * sin(2 * pi * 100 * ta) .* exp(-55 * ta);
                beatSound = 0.5 * [lub; zeros(round(0.04 * audioFs), 1); dub];
                beatPlayer = audioplayer(beatSound, audioFs);
                if ~isfile(smallImage) || ~isfile(largeImage)
                    uialert(app.UIFigure, 'Heartbeat image files are missing from the app folder.', 'Images unavailable');
                else
                    for k = 1:numel(rr)
                        beatStart = tic;
                        heartImage.ImageSource = largeImage;
                        if ~isempty(beatPlayer)
                            play(beatPlayer);
                        end
                        drawnow;
                        pause(min(0.12, rr(k)/3));
                        heartImage.ImageSource = smallImage;
                        drawnow;
                        pause(max(0, rr(k) - toc(beatStart)));
                    end
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % 读取采集结果
            data = app.UIFigure.UserData;
            if isempty(data)
                uialert(app.UIFigure, 'Acquire a signal before saving.', 'No data');
                return;
            end
            [file, path] = uiputfile('*.csv', 'Save ECG data');
            if isequal(file, 0)
                return;
            end
            N = numel(data.time);
            M = numel(data.frequency);
            % 整理频谱和 R 峰
            freq = nan(N, 1);
            rawSpectrum = nan(N, 1);
            filteredSpectrum = nan(N, 1);
            freq(1:M) = data.frequency;
            rawSpectrum(1:M) = data.rawAmplitude;
            filteredSpectrum(1:M) = data.filteredAmplitude;
            samplingRate = repmat(data.fs, N, 1);
            sampleIndex = (1:N)';
            rPeak = false(N, 1);
            rPeak(data.locs) = true;
            % 整理心率及状态
            meanHR = NaN;
            stdHR = NaN;
            if numel(data.locs) >= 2
                rr = diff(data.time(data.locs));
                if all(isfinite(rr)) && all(rr > 0)
                    instantaneousHR = 60 ./ rr;
                    meanHR = mean(instantaneousHR);
                    if numel(instantaneousHR) >= 2
                        stdHR = std(instantaneousHR, 0);
                    end
                end
            end
            heartRate = repmat(meanHR, N, 1);
            heartRateStd = repmat(stdHR, N, 1);
            statusText = strrep(app.StatusLabel.Text, 'Status: ', '');
            status = repmat({statusText}, N, 1);
            % 导出 CSV
            output = table(data.time, data.raw, data.filtered, samplingRate, ...
                freq, rawSpectrum, filteredSpectrum, sampleIndex, rPeak, heartRate, heartRateStd, status, ...
                'VariableNames', {'Time_s', 'Raw_V', 'Filtered_V', 'SamplingRate_Hz', ...
                'Frequency_Hz', 'Raw_Spectrum_V', 'Filtered_Spectrum_V', ...
                'Sample_Index', 'R_Peak', 'HeartRate_bpm', 'HeartRate_std_bpm', 'Status'});
            writetable(output, fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time-Domain Waveform')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Single-Sided Amplitude Spectrum')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Amplitude (V)')
            zlabel(app.UIAxes2, 'Z')
            app.ReadParameterButton = uibutton(app.UIFigure, 'push');
            app.ReadParameterButton.ButtonPushedFcn = createCallbackFcn(app, @ReadParameterButtonPushed, true);
            app.ReadParameterButton.Text = 'Read Parameter';
            app.SamplingRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateHzEditField.Value = 1000;
            app.AcquisitionDurationsEditFieldLabel = uilabel(app.UIFigure);
            app.AcquisitionDurationsEditFieldLabel.Text = 'Acquisition Duration (s)';
            app.AcquisitionDurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcquisitionDurationsEditField.Value = 10;
            app.SamplingRateLabel = uilabel(app.UIFigure);
            app.SamplingRateLabel.Text = 'Sampling Rate :';
            app.AcquisitionDurationLabel = uilabel(app.UIFigure);
            app.AcquisitionDurationLabel.Text = 'Acquisition Duration :';
            app.StartAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HeartRateLabel = uilabel(app.UIFigure);
            app.HeartRateLabel.Text = 'Heart Rate : ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status :';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'heart_big.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end