classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StatusLabel                    matlab.ui.control.Label
        HeartRateLabel                 matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        StartAcquisitionButton         matlab.ui.control.Button
        AcquisitionDurationLabel       matlab.ui.control.Label
        SamplingRateLabel              matlab.ui.control.Label
        AcquisitionDurationsEditField  matlab.ui.control.NumericEditField
        AcquisitionDurationsEditFieldLabel  matlab.ui.control.Label
        SamplingRateHzEditField        matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel   matlab.ui.control.Label
        ReadParameterButton            matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SampleRate
        Acqtime
        V
        t   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadParameterButton
        function ReadParameterButtonPushed(app, event)
            app.SampleRate = app.SamplingRateHzEditField.Value;
            app.Acqtime = app.AcquisitionDurationsEditField.Value;
            app.SamplingRateLabel.Text = ['Sampling Rate:', num2str(app.SampleRate), ' (Hz)'];
            app.AcquisitionDurationLabel.Text=['Acqusition Duration:', num2str(app.Acqtime),' (Sec)'];
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            s = daq.createSession('ni');
            addAnalogInputChannel(s, 'Dev11', 0, 'Voltage');

            s.Rate = app.SamplingRateHzEditField.Value;
            s.DurationInSeconds = app.AcquisitionDurationsEditField.Value;

            % 采集并保存到 app 属性
            [app.V, app.t] = s.startForeground;
            app.SampleRate = s.Rate;
            release(s);

            % 显示原始波形
            plot(app.UIAxes, app.t, app.V);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Voltage (V)');
            grid(app.UIAxes, 'on');

            % 计算单边 FFT 幅度谱
            N = length(app.V);
            x = app.V - mean(app.V);  
            Y = abs(fft(x) / N);
            A = Y(1:floor(N/2)+1);

            if mod(N, 2) == 0
                A(2:end-1) = 2*A(2:end-1);
            else
                A(2:end) = 2*A(2:end);
            end

            f = (0:floor(N/2)) * app.SampleRate/N;

            % 显示频谱
            plot(app.UIAxes2, f, A);
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Amplitude (V)');
            xlim(app.UIAxes2, [0 min(100, app.SampleRate/2)]);
            grid(app.UIAxes2, 'on');


            % locs represents the sample indices of detected R peaks
            RR = diff(app.t(locs));    % R-R intervals in seconds
            HR = 60 ./ RR;            % Heart rate for each interval

            meanHR = mean(HR);
            stdHR = std(HR);

            app.HeartRateLabel.Text = sprintf( 'Heart Rate: %.1f ± %.1f bpm', meanHR, stdHR);


            if meanHR < 60
                app.StatusLabel.Text = 'Status: Low Heart Rate';
                app.StatusLabel.FontColor = [0.8 0 0];
            elseif meanHR > 120
                app.StatusLabel.Text = 'Status: High Heart Rate';
                app.StatusLabel.FontColor = [0.8 0 0];
            else
                app.StatusLabel.Text = 'Status: Normal';
                app.StatusLabel.FontColor = [0 0.5 0];
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            [file, path] = uiputfile('*.csv', '保存数据');
            data = table(app.t, app.V, 'VariableNames', {'Time_s', 'Voltage_V'});
            writetable(data, fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time-Domain Waveform')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Single-Sided Amplitude Spectrum')
            xlabel(app.UIAxes2, 'Frequency (Hz)')
            ylabel(app.UIAxes2, 'Amplitude (V)')
            zlabel(app.UIAxes2, 'Z')
            app.ReadParameterButton = uibutton(app.UIFigure, 'push');
            app.ReadParameterButton.ButtonPushedFcn = createCallbackFcn(app, @ReadParameterButtonPushed, true);
            app.ReadParameterButton.Text = 'Read Parameter';
            app.SamplingRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateHzEditField.Value = 1000;
            app.AcquisitionDurationsEditFieldLabel = uilabel(app.UIFigure);
            app.AcquisitionDurationsEditFieldLabel.Text = 'Acquisition Duration (s)';
            app.AcquisitionDurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcquisitionDurationsEditField.Value = 10;
            app.SamplingRateLabel = uilabel(app.UIFigure);
            app.SamplingRateLabel.Text = 'Sampling Rate :';
            app.AcquisitionDurationLabel = uilabel(app.UIFigure);
            app.AcquisitionDurationLabel.Text = 'Acquisition Duration :';
            app.StartAcquisitionButton = uibutton(app.UIFigure, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HeartRateLabel = uilabel(app.UIFigure);
            app.HeartRateLabel.Text = 'Heart Rate : ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end